classdef DP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        R1EditFieldLabel     matlab.ui.control.Label
        R1                   matlab.ui.control.NumericEditField
        R2EditFieldLabel     matlab.ui.control.Label
        R2                   matlab.ui.control.NumericEditField
        R2SliderLabel        matlab.ui.control.Label
        R2S                  matlab.ui.control.Slider
        A1EditFieldLabel     matlab.ui.control.Label
        A1                   matlab.ui.control.NumericEditField
        A2EditFieldLabel     matlab.ui.control.Label
        A2                   matlab.ui.control.NumericEditField
        R1SliderLabel        matlab.ui.control.Label
        R1S                  matlab.ui.control.Slider
        A1SliderLabel        matlab.ui.control.Label
        A1S                  matlab.ui.control.Slider
        A2SliderLabel        matlab.ui.control.Label
        A2S                  matlab.ui.control.Slider
        PresasLabel_2        matlab.ui.control.Label
        Y0S                  matlab.ui.control.Knob
        DepredadoresLabel_2  matlab.ui.control.Label
        X0S                  matlab.ui.control.Knob
        DepredadoresLabel    matlab.ui.control.Label
        X0                   matlab.ui.control.NumericEditField
        PresasLabel          matlab.ui.control.Label
        Y0                   matlab.ui.control.NumericEditField
        Panel                matlab.ui.container.Panel
        R1TasadecrecimientodelaspresasLabel  matlab.ui.control.Label
        R2TasadecrecimientodelosdepredadoresLabel  matlab.ui.control.Label
        A1ExitoenlacazadeldepredadorLabel  matlab.ui.control.Label
        A2NoexitoenlacazadeldepredadorLabel  matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        GraficaTab           matlab.ui.container.Tab
        Grafica              matlab.ui.control.UIAxes
        Tab                  matlab.ui.container.Tab
        UIAxes               matlab.ui.control.UIAxes
        DatosTab             matlab.ui.container.Tab
        LuisFelipePintoOrtegon1803193Label  matlab.ui.control.Label
        LuisStevenParraPrieto1803289Label  matlab.ui.control.Label
        Button               matlab.ui.control.Button
    end


    properties (Access = public)
        %Tiempo en decadas
        t0=0; %Valor inicial de tiempo
        tf=250; %Valor final de tiempo
        h=0.1;
    end

    methods (Access = private)
    
        function [t,x,y]=RK4(app)
            vox=app.X0.Value;
            voy=app.Y0.Value;
            a1=app.A1.Value;
            a2=app.A2.Value;
            r1=app.R1.Value;
            r2=app.R2.Value;
            fR=@(t,x,y) a2.*x.*y-r2.*x;
            fF=@(t,x,y) r1.*y-a1.*x.*y;
            t(1)=app.t0;
            x(1)=vox;
            y(1)=voy;
            N=ceil(app.tf/app.h);
            
            for i=1:N
                t(i+1)=t(i)+app.h;
                
                K1R=fR(t(i),x(i),y(i));
                K1F=fF(t(i),x(i),y(i));
                
                K2R=fR(t(i)+app.h/2,x(i)+(app.h/2)*K1R,y(i)+(app.h/2)*K1F);
                K2F=fF(t(i)+app.h/2,x(i)+(app.h/2)*K1R,y(i)+(app.h/2)*K1F);
                
                K3R=fR(t(i)+app.h/2,x(i)+(app.h/2)*K2R,y(i)+(app.h/2)*K2F);
                K3F=fF(t(i)+app.h/2,x(i)+(app.h/2)*K2R,y(i)+(app.h/2)*K2F);
                
                K4R=fR(t(i)+app.h,x(i)+(app.h)*K3R,y(i)+(app.h)*K3F);
                K4F=fF(t(i)+app.h,x(i)+(app.h)*K3R,y(i)+(app.h)*K3F);
                
                x(i+1)=x(i)+(app.h/6)*(K1R + 2*K2R + 2*K3R + K4R);
                y(i+1)=y(i)+(app.h/6)*(K1F + 2*K2F + 2*K3F + K4F);
            end
            
        end        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.X0.Value=10;
            app.Y0.Value=0;
            app.A1.Value=0;
            app.A2.Value=0;
            app.R1.Value=0;
            app.R2.Value=0;
        end

        % Value changed function: R1S
        function R1SValueChanged(app, event)
            value = app.R1S.Value; %Obtengo el valor del slider
            value=round(value*100);%Redondea el valor a 3 cifras
            value=floor(value/5)*0.05;
            app.R1S.Value = value;
            app.R1.Value=value; %Muestro el valor del slider
            [t,x,y]=RK4(app);
            plot(app.Grafica,t,x(1,:),'-.',t,y(1,:),'-.');
            plot(app.UIAxes,y(1,:),x(1,:));
        end

        % Value changed function: R2S
        function R2SValueChanged(app, event)
            value = app.R2S.Value;
            value=round(value*100);
            value=floor(value/5)*0.05;
            app.R2S.Value=value;
            app.R2.Value=value;
            [t,x,y]=RK4(app);
            plot(app.Grafica,t,x(1,:),'-.',t,y(1,:),'-.');
            plot(app.UIAxes,y(1,:),x(1,:));
        end

        % Value changed function: A1S
        function A1SValueChanged(app, event)
            value = app.A1S.Value;
            value=round(value*100);
            value=floor(value)*0.01;
            app.A1S.Value=value;
            app.A1.Value=value;
            [t,x,y]=RK4(app);
            plot(app.Grafica,t,x(1,:),'-.',t,y(1,:),'-.');
            plot(app.UIAxes,y(1,:),x(1,:));
        end

        % Value changed function: A2S
        function A2SValueChanged(app, event)
            value = app.A2S.Value;
            value=round(value*100);
            value=floor(value)*0.01;
            app.A2S.Value=value;
            app.A2.Value=value;
            [t,x,y]=RK4(app);
            plot(app.Grafica,t,x(1,:),'-.',t,y(1,:),'-.');
            plot(app.UIAxes,y(1,:),x(1,:));
        end

        % Value changed function: X0S
        function X0SValueChanged(app, event)
            value = app.X0S.Value;
            value=floor(round(value));
            app.X0.Value=value;
            [t,x,y]=RK4(app);
            plot(app.Grafica,t,x(1,:),'-.',t,y(1,:),'-.');
            plot(app.UIAxes,y(1,:),x(1,:));
        end

        % Value changed function: Y0S
        function Y0SValueChanged(app, event)
            value = app.Y0S.Value;
            value=floor(round(value));
            app.Y0.Value=value;
            [t,x,y]=RK4(app);
            plot(app.Grafica,t,x(1,:),t,y(1,:),'-.');
            plot(app.UIAxes,y(1,:),x(1,:));
        end

        % Callback function
        function RUNButtonPushed(app, event)
%             app.Grafica.Legend=('Depredador','Presa');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1 = uieditfield(app.UIFigure, 'numeric');
            app.R1.Editable = 'off';
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2 = uieditfield(app.UIFigure, 'numeric');
            app.R2.Editable = 'off';
            app.R2SliderLabel = uilabel(app.UIFigure);
            app.R2SliderLabel.Text = 'R2';
            app.R2S = uislider(app.UIFigure);
            app.R2S.Limits = [0 1];
            app.R2S.MajorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.R2S.MajorTickLabels = {'0', '0.05', '0.1', '0.15', '0.2', '0.25', '0.3', '0.35', '0.4', '0.45', '0.5', '0.55', '0.6', '0.65', '0.7', '0.75', '0.8', '0.85', '0.9', '0.95', '1'};
            app.R2S.ValueChangedFcn = createCallbackFcn(app, @R2SValueChanged, true);
            app.R2S.MinorTicks = [];
            app.A1EditFieldLabel = uilabel(app.UIFigure);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1 = uieditfield(app.UIFigure, 'numeric');
            app.A1.Editable = 'off';
            app.A2EditFieldLabel = uilabel(app.UIFigure);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2 = uieditfield(app.UIFigure, 'numeric');
            app.A2.Editable = 'off';
            app.R1SliderLabel = uilabel(app.UIFigure);
            app.R1SliderLabel.Text = 'R1';
            app.R1S = uislider(app.UIFigure);
            app.R1S.Limits = [0 1];
            app.R1S.MajorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.R1S.MajorTickLabels = {'0', '0.05', '0.1', '0.15', '0.2', '0.25', '0.3', '0.35', '0.4', '0.45', '0.5', '0.55', '0.6', '0.65', '0.7', '0.75', '0.8', '0.85', '0.9', '0.95', '1'};
            app.R1S.ValueChangedFcn = createCallbackFcn(app, @R1SValueChanged, true);
            app.R1S.MinorTicks = [];
            app.A1SliderLabel = uilabel(app.UIFigure);
            app.A1SliderLabel.Text = 'A1';
            app.A1S = uislider(app.UIFigure);
            app.A1S.Limits = [0 1];
            app.A1S.MajorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.A1S.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '1'};
            app.A1S.ValueChangedFcn = createCallbackFcn(app, @A1SValueChanged, true);
            app.A1S.MinorTicks = [];
            app.A2SliderLabel = uilabel(app.UIFigure);
            app.A2SliderLabel.Text = 'A2';
            app.A2S = uislider(app.UIFigure);
            app.A2S.Limits = [0 1];
            app.A2S.MajorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.A2S.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '1'};
            app.A2S.ValueChangedFcn = createCallbackFcn(app, @A2SValueChanged, true);
            app.A2S.MinorTicks = [];
            app.PresasLabel_2 = uilabel(app.UIFigure);
            app.PresasLabel_2.Text = 'Presas';
            app.Y0S = uiknob(app.UIFigure, 'continuous');
            app.Y0S.Limits = [0 50];
            app.Y0S.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.Y0S.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '50'};
            app.Y0S.ValueChangedFcn = createCallbackFcn(app, @Y0SValueChanged, true);
            app.Y0S.MinorTicks = [];
            app.DepredadoresLabel_2 = uilabel(app.UIFigure);
            app.DepredadoresLabel_2.Text = 'Depredadores';
            app.X0S = uiknob(app.UIFigure, 'continuous');
            app.X0S.Limits = [10 50];
            app.X0S.MajorTicks = [10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.X0S.MajorTickLabels = {'10', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '50'};
            app.X0S.ValueChangedFcn = createCallbackFcn(app, @X0SValueChanged, true);
            app.X0S.MinorTicks = [];
            app.X0S.Value = 10;
            app.DepredadoresLabel = uilabel(app.UIFigure);
            app.DepredadoresLabel.Text = 'Depredadores';
            app.X0 = uieditfield(app.UIFigure, 'numeric');
            app.X0.Editable = 'off';
            app.PresasLabel = uilabel(app.UIFigure);
            app.PresasLabel.Text = 'Presas';
            app.Y0 = uieditfield(app.UIFigure, 'numeric');
            app.Y0.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.R1TasadecrecimientodelaspresasLabel = uilabel(app.Panel);
            app.R1TasadecrecimientodelaspresasLabel.Text = 'R1 : Tasa de crecimiento de las presas';
            app.R2TasadecrecimientodelosdepredadoresLabel = uilabel(app.Panel);
            app.R2TasadecrecimientodelosdepredadoresLabel.Text = 'R2 : Tasa de crecimiento de los depredadores';
            app.A1ExitoenlacazadeldepredadorLabel = uilabel(app.Panel);
            app.A1ExitoenlacazadeldepredadorLabel.Text = 'A1 : Exito en la caza del depredador';
            app.A2NoexitoenlacazadeldepredadorLabel = uilabel(app.Panel);
            app.A2NoexitoenlacazadeldepredadorLabel.Text = 'A2 : No exito en la caza del depredador';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraficaTab = uitab(app.TabGroup);
            app.GraficaTab.Title = 'Grafica';
            app.Grafica = uiaxes(app.GraficaTab);
            title(app.Grafica, 'Depredador/Presa')
            xlabel(app.Grafica, 'Generacion')
            ylabel(app.Grafica, 'Numero de Animales')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Diagrama de fase')
            xlabel(app.UIAxes, 'Presas')
            ylabel(app.UIAxes, 'Depredadores')
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.LuisFelipePintoOrtegon1803193Label = uilabel(app.DatosTab);
            app.LuisFelipePintoOrtegon1803193Label.Text = 'Luis Felipe Pinto Ortegon 1803193';
            app.LuisStevenParraPrieto1803289Label = uilabel(app.DatosTab);
            app.LuisStevenParraPrieto1803289Label.Text = 'Luis Steven Parra Prieto 1803289';
            app.Button = uibutton(app.DatosTab, 'push');
            app.Button.Icon = 'LOGO_UMNG.png';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end