classdef Intento1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PresentacinTab               matlab.ui.container.Tab
        JoanAlfonsoVelazquesLabel    matlab.ui.control.Label
        ModeloContagiadosvsnocontagiadosTab  matlab.ui.container.Tab
        PersonassinvirusKnobLabel    matlab.ui.control.Label
        NoVirusPeople                matlab.ui.control.Knob
        PersonasconelvirusKnobLabel  matlab.ui.control.Label
        VirusPeople                  matlab.ui.control.Knob
        R1TasadecrecimientocontagiadosLabel  matlab.ui.control.Label
        R1                           matlab.ui.control.NumericEditField
        R2TasadecrecimientonocontagiadosLabel  matlab.ui.control.Label
        R2                           matlab.ui.control.NumericEditField
        A1xitoenelcontagiodelvirusLabel  matlab.ui.control.Label
        A1                           matlab.ui.control.NumericEditField
        A2NoxitoenelcontagiodelvirusLabel  matlab.ui.control.Label
        A2                           matlab.ui.control.NumericEditField
        R1Label                      matlab.ui.control.Label
        R1Slider                     matlab.ui.control.Slider
        R2Label                      matlab.ui.control.Label
        R2Slider                     matlab.ui.control.Slider
        A1SliderLabel                matlab.ui.control.Label
        A1Slider                     matlab.ui.control.Slider
        A2SliderLabel                matlab.ui.control.Label
        A2Slider                     matlab.ui.control.Slider
        XNmerodecontagiadosLabel     matlab.ui.control.Label
        X_0                          matlab.ui.control.NumericEditField
        YNmerodenocontagiadosLabel   matlab.ui.control.Label
        Y_0                          matlab.ui.control.NumericEditField
        VirusvsNoV                   matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        GrficasTab                   matlab.ui.container.Tab
    end


    properties (Access = public)
        ti= 0;  %TIempo inicial
        tf = 300;   %Tiempo final
        h = 0.1;
    end
    
    methods (Access = public)
        
        function [t,x,y] = RoungeKutta(app)
            x_0 = app.X_0.Value;
            y_0 = app.Y_0.Value;
   
            a1 = app.A1.Value;
            a2 = app.A2.Value;
            
            r1 = app.R1.Value;
            r2 = app.R2.Value;
            dx= @(t,x,y) a2.*x.*y-r2.*x;
            dy= @(t,x,y) r1.*y-a1.*x.*y;
            t(1) = app.ti;
            x(1) = x_0;
            y(1) = y_0;
            Iteraciones = ceil ((app.tf-app.ti)/app.h);
            
            for i =1:Iteraciones
                t(i+1)=t(i)+app.h;
                
                K1X = dx(t(i),x(i),y(i));
                K1Y = dy(t(i),x(i),y(i));
                
                K2X = dx(t(i)+app.h/2, x(i)+(K1X*app.h/2), y(i)+(K1Y*app.h/2));
                K2Y = dy(t(i)+app.h/2, x(i)+(K1X*app.h/2), y(i)+(K1Y*app.h/2));
                
                K3X = dx(t(i)+app.h/2, x(i)+(K2X*app.h/2), y(i)+(K2Y*app.h/2));
                K3Y = dy(t(i)+app.h/2, x(i)+(K2X*app.h/2), y(i)+(K2Y*app.h/2));
                
                K4X = dx(t(i)+app.h, x(i)+(K3X*app.h), y(i)+(K3Y*app.h));
                K4Y = dy(t(i)+app.h, x(i)+(K3X*app.h), y(i)+(K3Y*app.h));
                
                x(i+1) = x(i)+((app.h/6)*(K1X + 2*K2X + 2*K3X + K4X));
                y(i+1) = y(i)+((app.h/6)*(K1Y + 2*K2Y + 2*K3Y + K4Y)) ;


            end
        end
    end

    methods (Access = private)

        % Value changed function: R1Slider
        function R1SliderValueChanged(app, event)
            ValorSliderR1 = app.R1Slider.Value;
            ValorSliderR1 = round (ValorSliderR1*100);
            ValorSliderR1 = floor(ValorSliderR1/5)*0.05;
            app.R1Slider.Value = ValorSliderR1;
            app.R1.Value = ValorSliderR1;
            [t,x,y]=RoungeKutta(app);
            plot(app.VirusvsNoV,t,x(1,:),'b',t,y(1,:),'y');
            plot(app.UIAxes,y(1,:),x(1,:));


        end

        % Value changed function: R2Slider
        function R2SliderValueChanged(app, event)
            ValorSliderR2 = app.R2Slider.Value;
            ValorSliderR2 = round (ValorSliderR2*100);
            ValorSliderR2= floor(ValorSliderR2/5)*0.05;
            app.R2Slider.Value = ValorSliderR2;
            app.R2.Value= ValorSliderR2;
            [t,x,y]=RoungeKutta(app);
            plot(app.VirusvsNoV,t,x(1,:),'b',t,y(1,:),'y');
            plot(app.UIAxes,y(1,:),x(1,:));


        end

        % Value changed function: A1Slider
        function A1SliderValueChanged(app, event)
            value = app.A1Slider.Value;
            value = round (value*100);
            value = floor (value) *0.01;
            app.A1Slider.Value= value;
            app.A1.Value= value;
            [t,x,y]=RoungeKutta(app);
            plot(app.VirusvsNoV,t,x(1,:),'b',t,y(1,:),'y');
            plot(app.UIAxes,y(1,:),x(1,:));


        end

        % Value changed function: A2Slider
        function A2SliderValueChanged(app, event)
            value = app.A2Slider.Value;
            value = round (value*100);
            value = floor(value)*0.01;
            app.A2Slider.Value = value;
            app.A2.Value = value;
            [t,x,y]=RoungeKutta(app);
            plot(app.VirusvsNoV,t,x(1,:),'b',t,y(1,:),'y');
            plot(app.UIAxes,y(1,:),x(1,:));
            
               
        end

        % Value changed function: NoVirusPeople
        function NoVirusPeopleValueChanged(app, event)
            value = app.NoVirusPeople.Value;
            value = round(value);
            app.NoVirusPeople.Value= value;
            app.Y_0.Value=value;
            [t,x,y]=RoungeKutta(app);
            plot(app.VirusvsNoV,t,x(1,:),'b',t,y(1,:),'y');
            plot(app.UIAxes,y(1,:),x(1,:));
            
               total = round(app.X_0.Value) + round(app.Y_0.Value)  ;
                A = ones(1,round(app.X_0.Value)); % infectados
                B = zeros(1,round( app.Y_0.Value));% sanos 
                n = rand(1,total);
                k = 1:total;
                xx = [A B];
                
               gscatter(k , n, xx,'br','xo');
              
        end

        % Value changed function: VirusPeople
        function VirusPeopleValueChanged(app, event)
            value = app.VirusPeople.Value;
            value = round (value);
            app.VirusPeople.Value = value;
            app.X_0.Value = value;
            [t,x,y]=RoungeKutta(app);
            plot(app.VirusvsNoV,t,x(1,:),'b',t,y(1,:),'y');
            plot(app.UIAxes,y(1,:),x(1,:));
            
               total = round(app.X_0.Value) + round(app.Y_0.Value)  ;
                A = ones(1,round( app.X_0.Value)); % infectados
                B = zeros(1,round(app.Y_0.Value));% sanos 
                n = rand(1,total);
                k = 1:total;
                xx = [A B];
             
                gscatter(k , n, xx,'br','xo');
               
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PresentacinTab = uitab(app.TabGroup);
            app.PresentacinTab.Title = 'Presentación';
            app.JoanAlfonsoVelazquesLabel = uilabel(app.PresentacinTab);
            app.JoanAlfonsoVelazquesLabel.Text = {'Joan Alfonso Velazques'; 'Daniel Quintanilla'; 'Joseph Enrique Rojas Pulido'};
            app.ModeloContagiadosvsnocontagiadosTab = uitab(app.TabGroup);
            app.ModeloContagiadosvsnocontagiadosTab.Title = 'Modelo Contagiados vs no contagiados';
            app.PersonassinvirusKnobLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.PersonassinvirusKnobLabel.Text = 'Personas sin virus';
            app.NoVirusPeople = uiknob(app.ModeloContagiadosvsnocontagiadosTab, 'continuous');
            app.NoVirusPeople.Limits = [0 50];
            app.NoVirusPeople.ValueChangedFcn = createCallbackFcn(app, @NoVirusPeopleValueChanged, true);
            app.PersonasconelvirusKnobLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.PersonasconelvirusKnobLabel.Text = 'Personas con el virus';
            app.VirusPeople = uiknob(app.ModeloContagiadosvsnocontagiadosTab, 'continuous');
            app.VirusPeople.Limits = [10 50];
            app.VirusPeople.MajorTicks = [10 20 30 40 50];
            app.VirusPeople.ValueChangedFcn = createCallbackFcn(app, @VirusPeopleValueChanged, true);
            app.VirusPeople.Value = 10;
            app.R1TasadecrecimientocontagiadosLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.R1TasadecrecimientocontagiadosLabel.Text = 'R1: Tasa de crecimiento contagiados';
            app.R1 = uieditfield(app.ModeloContagiadosvsnocontagiadosTab, 'numeric');
            app.R1.Editable = 'off';
            app.R2TasadecrecimientonocontagiadosLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.R2TasadecrecimientonocontagiadosLabel.Text = 'R2: Tasa de crecimiento no contagiados';
            app.R2 = uieditfield(app.ModeloContagiadosvsnocontagiadosTab, 'numeric');
            app.R2.Editable = 'off';
            app.A1xitoenelcontagiodelvirusLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.A1xitoenelcontagiodelvirusLabel.Text = 'A1: Éxito en el contagio del virus';
            app.A1 = uieditfield(app.ModeloContagiadosvsnocontagiadosTab, 'numeric');
            app.A1.Editable = 'off';
            app.A2NoxitoenelcontagiodelvirusLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.A2NoxitoenelcontagiodelvirusLabel.Text = 'A2:No éxito en el contagio del virus';
            app.A2 = uieditfield(app.ModeloContagiadosvsnocontagiadosTab, 'numeric');
            app.A2.Editable = 'off';
            app.R1Label = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.R1Label.Text = 'R1';
            app.R1Slider = uislider(app.ModeloContagiadosvsnocontagiadosTab);
            app.R1Slider.Limits = [0 1];
            app.R1Slider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '1'};
            app.R1Slider.ValueChangedFcn = createCallbackFcn(app, @R1SliderValueChanged, true);
            app.R2Label = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.R2Label.Text = 'R2';
            app.R2Slider = uislider(app.ModeloContagiadosvsnocontagiadosTab);
            app.R2Slider.Limits = [0 1];
            app.R2Slider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '1'};
            app.R2Slider.ValueChangedFcn = createCallbackFcn(app, @R2SliderValueChanged, true);
            app.A1SliderLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.A1SliderLabel.Text = 'A1';
            app.A1Slider = uislider(app.ModeloContagiadosvsnocontagiadosTab);
            app.A1Slider.Limits = [0 1];
            app.A1Slider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '1'};
            app.A1Slider.ValueChangedFcn = createCallbackFcn(app, @A1SliderValueChanged, true);
            app.A2SliderLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.A2SliderLabel.Text = 'A2';
            app.A2Slider = uislider(app.ModeloContagiadosvsnocontagiadosTab);
            app.A2Slider.Limits = [0 1];
            app.A2Slider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '1'};
            app.A2Slider.ValueChangedFcn = createCallbackFcn(app, @A2SliderValueChanged, true);
            app.XNmerodecontagiadosLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.XNmerodecontagiadosLabel.Text = 'X: Número de contagiados';
            app.X_0 = uieditfield(app.ModeloContagiadosvsnocontagiadosTab, 'numeric');
            app.X_0.Editable = 'off';
            app.YNmerodenocontagiadosLabel = uilabel(app.ModeloContagiadosvsnocontagiadosTab);
            app.YNmerodenocontagiadosLabel.Text = 'Y: Número de no contagiados';
            app.Y_0 = uieditfield(app.ModeloContagiadosvsnocontagiadosTab, 'numeric');
            app.Y_0.Editable = 'off';
            app.VirusvsNoV = uiaxes(app.ModeloContagiadosvsnocontagiadosTab);
            title(app.VirusvsNoV, 'Contagiados / No contagiados')
            xlabel(app.VirusvsNoV, 'Tiempo')
            ylabel(app.VirusvsNoV, 'Número de personas')
            app.UIAxes = uiaxes(app.ModeloContagiadosvsnocontagiadosTab);
            title(app.UIAxes, 'Diagrama de Fase')
            xlabel(app.UIAxes, 'No contagiados')
            ylabel(app.UIAxes, 'Contagiados')
            app.GrficasTab = uitab(app.TabGroup);
            app.GrficasTab.Title = 'Gráficas';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Intento1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end